classdef segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        browse_button               matlab.ui.control.Button
        path_field                  matlab.ui.control.EditField
        IntensityThresholdLabel     matlab.ui.control.Label
        intensity_threshold_slider  matlab.ui.control.Slider
        ConnectivityDropDownLabel   matlab.ui.control.Label
        connectivity_drop_down      matlab.ui.control.DropDown
        active_low_checkbox         matlab.ui.control.CheckBox
        original_image_axis         matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        BinaryTab                   matlab.ui.container.Tab
        binary_image_axis           matlab.ui.control.UIAxes
        SegmentedTab                matlab.ui.container.Tab
        segmented_image_axis        matlab.ui.control.UIAxes
        PostProcessedTab            matlab.ui.container.Tab
        post_processed_image_axis   matlab.ui.control.UIAxes
        pixelgrid_checkbox          matlab.ui.control.CheckBox
        AutosegmentCheckBox         matlab.ui.control.CheckBox
        SegmentButton               matlab.ui.control.Button
    end

    properties (Access = private)
        image
        binary_image
    end
    
    methods (Access = private)
        function update_image(app)
            % does this file exist?
            if ~isfile(app.path_field.Value)
                return;
            end
        
            % read it and normalize intensities
            app.image = imread(app.path_field.Value);
            app.image = normalize_ints(app.image);

            % convert to grayscale if necessary
            [~, ~, depth] = size(app.image);
            if depth > 1 
                app.image = rgb_to_grayscale(app.image);
            end
            
            % display image in the bottom right then generate the
            % binary/segmented images
            imshow(app.image, 'Parent', app.original_image_axis);
            redraw_binary_image(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            iptsetpref('ImshowInitialMagnification', 'fit');    
        
            imshow(1, 'Parent', app.original_image_axis);
            imshow(1, 'Parent', app.binary_image_axis);
            imshow(1, 'Parent', app.segmented_image_axis);
            imshow(1, 'Parent', app.post_processed_image_axis);
            axis(app.original_image_axis, 'tight');
            axis(app.binary_image_axis, 'tight');
            axis(app.segmented_image_axis, 'tight');
            axis(app.post_processed_image_axis, 'tight');
            
            app.path_field.Value = fullfile(pwd, 'img', 'medium_circle.bmp');
            update_image(app);
        end

        % Button pushed function: browse_button
        function browse_button_pushed(app, event)
            % ask user to select an image file
            [file, path] = uigetfile({'*.png; *.jpg; *.jpeg; *.bmp; *.tiff'}, 'Select an image', 'img');
            
            % if something was selected, update the path field
            if ~isequal(file, 0)
                app.path_field.Value = fullfile(path, file);
            end      
            
            % does this file exist?
            if ~isfile(app.path_field.Value)
                return;
            end
        
            % read it and normalize intensities
            app.image = imread(app.path_field.Value);
            app.image = normalize_ints(app.image);

            % convert to grayscale if necessary
            [~, ~, depth] = size(app.image);
            if depth > 1 
                app.image = rgb_to_grayscale(app.image);
            end
            
            % display image in the bottom right then generate the
            % binary/segmented images
            imshow(app.image, 'Parent', app.original_image_axis);
            redraw_binary_image(app);
        end

        % Button pushed function: SegmentButton
        function redraw_segmented_image(app, event)
            % segment the binary image
            % matrix w/ unique ids for each segment
            [segmented_image, partial_seg_map] = segment_image(app.binary_image, app.connectivity_drop_down.Value, app.UIFigure);     
            
            % create a colormap for the the segmented image
            % ignore segments that are smaller than a selected percentage
            % of the image size
            colormap = color_segmented_image(segmented_image);
            
            % display segmented image
            % increment segment ids to push indices into appropriate color of colormap
            imshow(segmented_image + 1, colormap, 'Parent', app.segmented_image_axis);
            
            % merge partial segments
            post_processed_image = merge_partial_segments(segmented_image, partial_seg_map, app.UIFigure);
            
            % color post processed image
            colormap = color_segmented_image(post_processed_image);
            
            % display the post-processed image
            imshow(post_processed_image + 1, colormap, 'Parent', app.post_processed_image_axis);
            
            % add pixel grid if checked
            if app.pixelgrid_checkbox.Value
               pixelgrid(app.post_processed_image_axis); 
            end
        end

        % Value changed function: pixelgrid_checkbox
        function pixelgrid_checkboxValueChanged(app, event)
            if app.pixelgrid_checkbox.Value
                pixelgrid(app.binary_image_axis);
                pixelgrid(app.segmented_image_axis);
                pixelgrid(app.post_processed_image_axis);
            else
                % todo more effient way to remove pixel grid?
                redraw_segmented_image(app);
            end
        end

        % Value changed function: connectivity_drop_down, 
        % intensity_threshold_slider
        function redraw_binary_image(app, event)
            % convert grayscale image to binary
            app.binary_image = app.image > app.intensity_threshold_slider.Value;
            
            % if user selected active low, invert that
            if app.active_low_checkbox.Value
                app.binary_image = ~app.binary_image;
            end
            
            % display binary image
            imshow(app.binary_image, 'Parent', app.binary_image_axis);
            
            % update segmented image
            if app.AutosegmentCheckBox.Value 
                redraw_segmented_image(app);
            end
        end

        % Value changed function: active_low_checkbox
        function active_low_checkboxValueChanged(app, event)
            redraw_binary_image(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.browse_button = uibutton(app.UIFigure, 'push');
            app.browse_button.ButtonPushedFcn = createCallbackFcn(app, @browse_button_pushed, true);
            app.browse_button.Text = 'Browse';
            app.path_field = uieditfield(app.UIFigure, 'text');
            app.path_field.Editable = 'off';
            app.IntensityThresholdLabel = uilabel(app.UIFigure);
            app.IntensityThresholdLabel.Text = {'Intensity'; 'Threshold'};
            app.intensity_threshold_slider = uislider(app.UIFigure);
            app.intensity_threshold_slider.Limits = [0.1 0.9];
            app.intensity_threshold_slider.MajorTicks = [0.1 0.5 0.9];
            app.intensity_threshold_slider.MajorTickLabels = {'0.1', '0.5', '0.9'};
            app.intensity_threshold_slider.ValueChangedFcn = createCallbackFcn(app, @redraw_binary_image, true);
            app.intensity_threshold_slider.MinorTicks = [];
            app.intensity_threshold_slider.Value = 0.7667;
            app.ConnectivityDropDownLabel = uilabel(app.UIFigure);
            app.ConnectivityDropDownLabel.Text = 'Connectivity';
            app.connectivity_drop_down = uidropdown(app.UIFigure);
            app.connectivity_drop_down.Items = {'4', '8', 'M'};
            app.connectivity_drop_down.ValueChangedFcn = createCallbackFcn(app, @redraw_binary_image, true);
            app.connectivity_drop_down.Value = '4';
            app.active_low_checkbox = uicheckbox(app.UIFigure);
            app.active_low_checkbox.ValueChangedFcn = createCallbackFcn(app, @active_low_checkboxValueChanged, true);
            app.active_low_checkbox.Text = 'Invert Segments';
            app.original_image_axis = uiaxes(app.UIFigure);
            title(app.original_image_axis, '')
            xlabel(app.original_image_axis, '')
            ylabel(app.original_image_axis, '')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BinaryTab = uitab(app.TabGroup);
            app.BinaryTab.Title = 'Binary';
            app.binary_image_axis = uiaxes(app.BinaryTab);
            title(app.binary_image_axis, '')
            xlabel(app.binary_image_axis, '')
            ylabel(app.binary_image_axis, '')
            app.SegmentedTab = uitab(app.TabGroup);
            app.SegmentedTab.Title = 'Segmented';
            app.segmented_image_axis = uiaxes(app.SegmentedTab);
            title(app.segmented_image_axis, '')
            xlabel(app.segmented_image_axis, '')
            ylabel(app.segmented_image_axis, '')
            app.PostProcessedTab = uitab(app.TabGroup);
            app.PostProcessedTab.Title = 'Post Processed';
            app.post_processed_image_axis = uiaxes(app.PostProcessedTab);
            title(app.post_processed_image_axis, '')
            xlabel(app.post_processed_image_axis, '')
            ylabel(app.post_processed_image_axis, '')
            app.pixelgrid_checkbox = uicheckbox(app.UIFigure);
            app.pixelgrid_checkbox.ValueChangedFcn = createCallbackFcn(app, @pixelgrid_checkboxValueChanged, true);
            app.pixelgrid_checkbox.Text = 'Pixel Grid';
            app.AutosegmentCheckBox = uicheckbox(app.UIFigure);
            app.AutosegmentCheckBox.Text = 'Auto segment';
            app.AutosegmentCheckBox.Value = true;
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @redraw_segmented_image, true);
            app.SegmentButton.Text = 'Segment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end