classdef skeletonization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        browse_button                  matlab.ui.control.Button
        path_field                     matlab.ui.control.EditField
        IntensityThresholdLabel        matlab.ui.control.Label
        intensity_threshold_slider     matlab.ui.control.Slider
        SkeletonizationTransformLabel  matlab.ui.control.Label
        mode_drop_down                 matlab.ui.control.DropDown
        active_low_checkbox            matlab.ui.control.CheckBox
        original_image_axis            matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        BinaryTab                      matlab.ui.container.Tab
        binary_image_axis              matlab.ui.control.UIAxes
        DistancesTab                   matlab.ui.container.Tab
        distances_image_axis           matlab.ui.control.UIAxes
        Scan1Tab                       matlab.ui.container.Tab
        scan1_image_axis               matlab.ui.control.UIAxes
        Scan2Tab                       matlab.ui.container.Tab
        scan2_image_axis               matlab.ui.control.UIAxes
        ResultTab                      matlab.ui.container.Tab
        result_image_axis              matlab.ui.control.UIAxes
        AutoskeletonizeCheckBox        matlab.ui.control.CheckBox
        SkeletonizeButton              matlab.ui.control.Button
    end

    properties (Access = private)
        image
        binary_image
    end
    
    methods (Access = private)
        function update_image(app)
            % does this file exist?
            if ~isfile(app.path_field.Value)
                return;
            end
        
            % read it and normalize intensities
            app.image = imread(app.path_field.Value);
            app.image = normalize_ints(app.image);

            % convert to grayscale if necessary
            [~, ~, depth] = size(app.image);
            if depth > 1 
                app.image = rgb_to_grayscale(app.image);
            end
            
            % display image in the bottom right then generate the
            % binary/segmented images
            imshow(app.image, 'Parent', app.original_image_axis);
            redraw_binary_image(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            iptsetpref('ImshowInitialMagnification', 'fit');    
        
            imshow(1, 'Parent', app.original_image_axis);
            imshow(1, 'Parent', app.binary_image_axis);
            imshow(1, 'Parent', app.distances_image_axis);
            imshow(1, 'Parent', app.scan1_image_axis);
            imshow(1, 'Parent', app.scan2_image_axis);
            imshow(1, 'Parent', app.result_image_axis);
            
            axis(app.original_image_axis, 'tight');
            axis(app.binary_image_axis, 'tight');
            axis(app.distances_image_axis, 'tight');
            axis(app.scan1_image_axis, 'tight');
            axis(app.scan2_image_axis, 'tight');
            axis(app.result_image_axis, 'tight');
            
            app.path_field.Value = fullfile(pwd, 'img', 'medium_circle.bmp');
            update_image(app);
        end

        % Button pushed function: browse_button
        function browse_button_pushed(app, event)
            % ask user to select an image file
            [file, path] = uigetfile({'*.png; *.jpg; *.jpeg; *.bmp; *.tiff'}, 'Select an image', 'img');
            
            % if something was selected, update the path field
            if ~isequal(file, 0)
                app.path_field.Value = fullfile(path, file);
            end      
            
            % does this file exist?
            if ~isfile(app.path_field.Value)
                return;
            end
        
            % read it and normalize intensities
            app.image = imread(app.path_field.Value);
            app.image = normalize_ints(app.image);

            % convert to grayscale if necessary
            [~, ~, depth] = size(app.image);
            if depth > 1 
                app.image = rgb_to_grayscale(app.image);
            end
            
            % display image in the bottom right then generate the
            % binary/segmented images
            imshow(app.image, 'Parent', app.original_image_axis);
            redraw_binary_image(app);
        end

        % Button pushed function: SkeletonizeButton
        function redraw_skeleton_images(app, event)
            switch app.mode_drop_down.Value
              case 'Medial Axis'
                mat = medial_axis_transform(app.binary_image, app.UIFigure);
                result = color_where(app.binary_image * 255, mat, [255 0 0]);
                imshow(result, 'Parent', app.result_image_axis);
                imshow(1, 'Parent', app.distances_image_axis);
                imshow(1, 'Parent', app.scan1_image_axis);
                imshow(1, 'Parent', app.scan2_image_axis);
              case 'Distance'
                distances = find_distances(app.binary_image, app.UIFigure);
                distances_norm = distances / max(distances(:));
                imshow(distances_norm, 'Parent', app.distances_image_axis);
                row_scan = ridge_scan_rows(distances_norm, app.UIFigure);
                imshow(row_scan, 'Parent', app.scan1_image_axis);
                col_scan = ridge_scan_cols(distances_norm, app.UIFigure);
                imshow(col_scan, 'Parent', app.scan2_image_axis);
                scans_combined = row_scan & col_scan;
                result = color_where(app.binary_image * 255, scans_combined, [255 0 0]);
                imshow(result, 'Parent', app.result_image_axis);
              case 'Laplacian 4'  
                distances = find_distances(app.binary_image, app.UIFigure);
                distances_norm = distances / max(distances(:));
                imshow(distances_norm, 'Parent', app.distances_image_axis);
                lap = laplacian_scan(distances, 4, app.UIFigure);
                imshow(lap, 'Parent', app.scan1_image_axis);
                imshow(1, 'Parent', app.scan2_image_axis);
                post_processed = laplacian_post_process(lap, app.UIFigure);
                result = color_where(app.binary_image * 255, post_processed, [255 0 0]);
                imshow(result, 'Parent', app.result_image_axis);
              case 'Laplacian 8'
                distances = find_distances(app.binary_image, app.UIFigure);
                distances_norm = distances / max(distances(:));
                imshow(distances_norm, 'Parent', app.distances_image_axis);
                lap = laplacian_scan(distances, 8, app.UIFigure);
                imshow(lap, 'Parent', app.scan1_image_axis);
                imshow(1, 'Parent', app.scan2_image_axis);
                post_processed = laplacian_post_process(lap, app.UIFigure);
                result = color_where(app.binary_image * 255, post_processed, [255 0 0]);
                imshow(result, 'Parent', app.result_image_axis);
            end
        end

        % Value changed function: intensity_threshold_slider, 
        % mode_drop_down
        function redraw_binary_image(app, event)
            % convert grayscale image to binary
            app.binary_image = app.image > app.intensity_threshold_slider.Value;
            
            % if user selected active low, invert that
            if app.active_low_checkbox.Value
                app.binary_image = ~app.binary_image;
            end
            
            % display binary image
            imshow(app.binary_image, 'Parent', app.binary_image_axis);
            
            % update segmented image
            if app.AutoskeletonizeCheckBox.Value 
                redraw_skeleton_images(app);
            end
        end

        % Value changed function: active_low_checkbox
        function active_low_checkboxValueChanged(app, event)
            redraw_binary_image(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.browse_button = uibutton(app.UIFigure, 'push');
            app.browse_button.ButtonPushedFcn = createCallbackFcn(app, @browse_button_pushed, true);
            app.browse_button.Text = 'Browse';
            app.path_field = uieditfield(app.UIFigure, 'text');
            app.path_field.Editable = 'off';
            app.IntensityThresholdLabel = uilabel(app.UIFigure);
            app.IntensityThresholdLabel.Text = {'Intensity'; 'Threshold'};
            app.intensity_threshold_slider = uislider(app.UIFigure);
            app.intensity_threshold_slider.Limits = [0.1 0.9];
            app.intensity_threshold_slider.MajorTicks = [0.1 0.5 0.9];
            app.intensity_threshold_slider.MajorTickLabels = {'0.1', '0.5', '0.9'};
            app.intensity_threshold_slider.ValueChangedFcn = createCallbackFcn(app, @redraw_binary_image, true);
            app.intensity_threshold_slider.MinorTicks = [];
            app.intensity_threshold_slider.Value = 0.7667;
            app.SkeletonizationTransformLabel = uilabel(app.UIFigure);
            app.SkeletonizationTransformLabel.Text = {'Skeletonization'; 'Transform'};
            app.mode_drop_down = uidropdown(app.UIFigure);
            app.mode_drop_down.Items = {'Medial Axis', 'Distance', 'Laplacian 4', 'Laplacian 8'};
            app.mode_drop_down.ValueChangedFcn = createCallbackFcn(app, @redraw_binary_image, true);
            app.mode_drop_down.Value = 'Medial Axis';
            app.active_low_checkbox = uicheckbox(app.UIFigure);
            app.active_low_checkbox.ValueChangedFcn = createCallbackFcn(app, @active_low_checkboxValueChanged, true);
            app.active_low_checkbox.Text = 'Invert Segments';
            app.original_image_axis = uiaxes(app.UIFigure);
            title(app.original_image_axis, '')
            xlabel(app.original_image_axis, '')
            ylabel(app.original_image_axis, '')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BinaryTab = uitab(app.TabGroup);
            app.BinaryTab.Title = 'Binary';
            app.binary_image_axis = uiaxes(app.BinaryTab);
            title(app.binary_image_axis, '')
            xlabel(app.binary_image_axis, '')
            ylabel(app.binary_image_axis, '')
            app.DistancesTab = uitab(app.TabGroup);
            app.DistancesTab.Title = 'Distances';
            app.distances_image_axis = uiaxes(app.DistancesTab);
            title(app.distances_image_axis, '')
            xlabel(app.distances_image_axis, '')
            ylabel(app.distances_image_axis, '')
            app.Scan1Tab = uitab(app.TabGroup);
            app.Scan1Tab.Title = 'Scan 1';
            app.scan1_image_axis = uiaxes(app.Scan1Tab);
            title(app.scan1_image_axis, '')
            xlabel(app.scan1_image_axis, '')
            ylabel(app.scan1_image_axis, '')
            app.Scan2Tab = uitab(app.TabGroup);
            app.Scan2Tab.Title = 'Scan 2';
            app.scan2_image_axis = uiaxes(app.Scan2Tab);
            title(app.scan2_image_axis, '')
            xlabel(app.scan2_image_axis, '')
            ylabel(app.scan2_image_axis, '')
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.result_image_axis = uiaxes(app.ResultTab);
            title(app.result_image_axis, '')
            xlabel(app.result_image_axis, '')
            ylabel(app.result_image_axis, '')
            app.AutoskeletonizeCheckBox = uicheckbox(app.UIFigure);
            app.AutoskeletonizeCheckBox.Text = 'Auto skeletonize';
            app.AutoskeletonizeCheckBox.Value = true;
            app.SkeletonizeButton = uibutton(app.UIFigure, 'push');
            app.SkeletonizeButton.ButtonPushedFcn = createCallbackFcn(app, @redraw_skeleton_images, true);
            app.SkeletonizeButton.Text = 'Skeletonize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = skeletonization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end